classdef ERLOM < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        RightPanel                 matlab.ui.container.Panel
        tablepH                    matlab.ui.control.Table
        picpHc                     matlab.ui.control.UIAxes
        picpH                      matlab.ui.control.UIAxes
        CenterPanel                matlab.ui.container.Panel
        tableOP                    matlab.ui.control.Table
        picOPc                     matlab.ui.control.UIAxes
        picOP                      matlab.ui.control.UIAxes
        LeftPanel                  matlab.ui.container.Panel
        Label_17                   matlab.ui.control.Label
        pHLabel                    matlab.ui.control.Label
        pHstep                     matlab.ui.control.NumericEditField
        EditField_4Label_2         matlab.ui.control.Label
        pHdown                     matlab.ui.control.NumericEditField
        EditField_3Label_3         matlab.ui.control.Label
        pHup                       matlab.ui.control.NumericEditField
        EditFieldLabel_2           matlab.ui.control.Label
        yitading                   matlab.ui.control.NumericEditField
        Label_11                   matlab.ui.control.Label
        Label_9                    matlab.ui.control.Label
        M_OPButton                 matlab.ui.control.Button
        BV_OPButton                matlab.ui.control.Button
        MarcusMGkineticsLabel      matlab.ui.control.Label
        ButlerVolmerkineticsLabel  matlab.ui.control.Label
        gama                       matlab.ui.control.NumericEditField
        Label_7                    matlab.ui.control.Label
        beita                      matlab.ui.control.NumericEditField
        Label_6                    matlab.ui.control.Label
        Ea0                        matlab.ui.control.NumericEditField
        Ea0Label                   matlab.ui.control.Label
        pHding                     matlab.ui.control.NumericEditField
        pHEditFieldLabel           matlab.ui.control.Label
        Label_2                    matlab.ui.control.Label
        OPdown                     matlab.ui.control.NumericEditField
        EditField_2Label_2         matlab.ui.control.Label
        OPstep                     matlab.ui.control.NumericEditField
        EditField_3Label_2         matlab.ui.control.Label
        OPup                       matlab.ui.control.NumericEditField
        Label                      matlab.ui.control.Label
        MG_OPButton                matlab.ui.control.Button
        lanmuda4                   matlab.ui.control.NumericEditField
        EditField_4Label           matlab.ui.control.Label
        lanmuda3                   matlab.ui.control.NumericEditField
        EditField_3Label           matlab.ui.control.Label
        lanmuda2                   matlab.ui.control.NumericEditField
        EditField_2Label           matlab.ui.control.Label
        lanmuda1                   matlab.ui.control.NumericEditField
        EditFieldLabel             matlab.ui.control.Label
        G4EditField                matlab.ui.control.NumericEditField
        G4EditFieldLabel           matlab.ui.control.Label
        G3EditField                matlab.ui.control.NumericEditField
        G3EditFieldLabel           matlab.ui.control.Label
        G2EditField                matlab.ui.control.NumericEditField
        G2EditFieldLabel           matlab.ui.control.Label
        G1EditField                matlab.ui.control.NumericEditField
        G1EditFieldLabel           matlab.ui.control.Label
        ERLOMLabel                 matlab.ui.control.Label
        Label_13                   matlab.ui.control.Label
        Label_14                   matlab.ui.control.Label
        Label_15                   matlab.ui.control.Label
        Label_16                   matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: MG_OPButton
        function MG_OPButtonPushed(app, event)
%% 创建ERLOM数据输出Excel表格文件
filename = 'ERLOM.xlsx';  % 指定要删除的文件名 
if exist("ERLOM.xlsx", 'file') == 2 % 检查文件是否存在 
    delete(filename); % 删除文件 
end 
% 创建空白表格
file = 'ERLOM.xlsx'; 
A = []; B = []; C =[]; D = [];
writematrix(A,file,"Sheet",'Sheet1');
writematrix(B,file,"Sheet",'Sheet2');
writematrix(C,file,"Sheet",'Sheet3');
writematrix(D,file,"Sheet",'Sheet4');

% 标题行和相关参数
TitleRow1 = {'参数','数值','单位','η','k1','k-1','k2','k-2','k3','k-3','k4','k-4',...
            'Θ-OMV','Θ-OMOH','ΘOMO','ΘOMOOH','r1','r2','r3','r4','log(r)'};
TitleRow2 = {'参数','数值','单位','pH','k1','k-1','k2','k-2','k3','k-3','k4','k-4',...
            'Θ-OMV','Θ-OMOH','ΘOMO','ΘOMOOH','r1','r2','r3','r4','log(r)'};
CanShuNameOP = {'kBT/h','kBT','T','f','Ea0','γ','β','pH','ΔG1','ΔG2','ΔG3','ΔG4',...
            'λ1','λ2','λ3','λ4'}';
CanShuNamepH = {'kBT/h','kBT','T','f','Ea0','γ','β','η','ΔG1','ΔG2','ΔG3','ΔG4',...
            'λ1','λ2','λ3','λ4'}';
CanShuData = [6220889369003.16;0.0256925694830201;298.15;38.9217590969594];
CanShuUnit = {'s-1','eV','K','V-1','eV','','','','eV','eV','eV','eV','eV','eV','eV','eV'}';

% 写入随变化表格参数
writecell(TitleRow1,file,"Sheet",1,'Range','A1');
writecell(CanShuNameOP,file,"Sheet",1,'Range','A2');
writematrix(CanShuData,file,"Sheet",1,'Range','B2');
writecell(CanShuUnit,file,"Sheet",1,'Range','C2');
% 写入随pH变化表格参数
writecell(TitleRow2,file,"Sheet",3,'Range','A1');
writecell(CanShuNamepH,file,"Sheet",3,'Range','A2');
writematrix(CanShuData,file,"Sheet",3,'Range','B2');
writecell(CanShuUnit,file,"Sheet",3,'Range','C2');

%% 获取参数并运行
filename = 'ERLOM.xlsx';
yita = (app.OPdown.Value:app.OPstep.Value:app.OPup.Value)';
pH = (app.pHdown.Value:app.pHstep.Value:app.pHup.Value)';

writematrix(app.G1EditField.Value,filename,'Sheet',1, 'Range', 'B10');
writematrix(app.G2EditField.Value,filename,'Sheet',1, 'Range', 'B11');
writematrix(app.G3EditField.Value,filename,'Sheet',1, 'Range', 'B12');
writematrix(app.G4EditField.Value,filename,'Sheet',1, 'Range', 'B13');
writematrix(app.G1EditField.Value,filename,'Sheet',3, 'Range', 'B10');
writematrix(app.G2EditField.Value,filename,'Sheet',3, 'Range', 'B11');
writematrix(app.G3EditField.Value,filename,'Sheet',3, 'Range', 'B12');
writematrix(app.G4EditField.Value,filename,'Sheet',3, 'Range', 'B13');

writematrix(app.lanmuda1.Value,filename,'Sheet', 1 , 'Range', 'B14');
writematrix(app.lanmuda2.Value,filename,'Sheet', 1 , 'Range', 'B15');
writematrix(app.lanmuda3.Value,filename,'Sheet', 1 , 'Range', 'B16');
writematrix(app.lanmuda4.Value,filename,'Sheet', 1 , 'Range', 'B17');
writematrix(app.lanmuda1.Value,filename,'Sheet', 3 , 'Range', 'B14');
writematrix(app.lanmuda2.Value,filename,'Sheet', 3 , 'Range', 'B15');
writematrix(app.lanmuda3.Value,filename,'Sheet', 3 , 'Range', 'B16');
writematrix(app.lanmuda4.Value,filename,'Sheet', 3 , 'Range', 'B17');

writematrix(yita,filename,'Sheet', 1, 'Range', 'D2');
writematrix(pH,filename,'Sheet', 3, 'Range', 'D2');
writematrix(app.pHding.Value,filename,'Sheet',1, 'Range', 'B9');
writematrix(app.yitading.Value,filename,'Sheet',3, 'Range', 'B9');

run("ERLOM_MG.m");

t1 = readtable("ERLOM.xlsx",'VariableNamingRule', 'preserve',"Sheet",1,"Range",'D:U');
t2 = readtable("ERLOM.xlsx",'VariableNamingRule', 'preserve',"Sheet",3,"Range",'D:U');
app.tableOP.Data = t1;
app.tablepH.Data = t2;

% 获取η数据作为x轴
x1 = table2array(t1(:,1));
% 获取log(r)作为y轴
y1 = table2array(t1(:,18));
% 获取pH数据作为x轴
x2 = table2array(t2(:,1));
% 获取log(r)作为y轴
y2 = table2array(t2(:,18));

% 获取覆盖度数值作为y轴
y3 = table2array(t1(:,10));
y4 = table2array(t1(:,11));
y5 = table2array(t1(:,12));
y6 = table2array(t1(:,13));
y7 = table2array(t2(:,10));
y8 = table2array(t2(:,11));
y9 = table2array(t2(:,12));
y10 = table2array(t2(:,13));

% 清除异常数据 
y1max = 200;
y2max = 200;
valid_indices1 = y1 < y1max;  % 生成逻辑索引 
valid_indices2 = y2 < y2max;  % 生成逻辑索引 
x1_clean = x1(valid_indices1);
y1_clean = y1(valid_indices1);
x2_clean = x2(valid_indices2);
y2_clean = y2(valid_indices2);

% 清除坐标轴并绘制过电势η图形 
cla(app.picOP);
plot(app.picOP, x1_clean, y1_clean);
cla(app.picOPc);
plot(app.picOPc,x1,y3,'r',x1,y4,'b',x1,y5,'g',x1,y6,'y');
% 清除坐标轴并绘制酸碱度pH图形 
cla(app.picpH);
plot(app.picpH, x2_clean, y2_clean);
cla(app.picpHc);
plot(app.picpHc,x2,y7,'r',x2,y8,'b',x2,y9,'g',x2,y10,'y');


% 添加图例 
legend(app.picOPc,'θOMV', 'θOMOH', 'θOMO', 'θOMOOH');
legend(app.picpHc,'θOMV', 'θOMOH', 'θOMO', 'θOMOOH');


% 添加坐标轴标签 
app.picOP.XLabel.String = 'η';
app.picOP.YLabel.String = 'log(r)';
app.picOP.Title.String = 'η-log(r)';
app.picOPc.XLabel.String = 'η';
app.picOPc.YLabel.String = 'θ';
app.picOPc.Title.String = 'η-θ';
app.picpH.XLabel.String = 'pH';
app.picpH.YLabel.String = 'log(r)';
app.picpH.Title.String = 'pH-log(r)';
app.picpHc.XLabel.String = 'pH';
app.picpHc.YLabel.String = 'θ';
app.picpHc.Title.String = 'pH-θ';
        end

        % Button pushed function: BV_OPButton
        function BV_OPButtonPushed(app, event)
%% 创建ERLOM数据输出Excel表格文件
filename = 'ERLOM.xlsx';  % 指定要删除的文件名 
if exist("ERLOM.xlsx", 'file') == 2 % 检查文件是否存在 
    delete(filename); % 删除文件 
end 
% 创建空白表格
file = 'ERLOM.xlsx'; 
A = []; B = []; C =[]; D = [];
writematrix(A,file,"Sheet",'Sheet1');
writematrix(B,file,"Sheet",'Sheet2');
writematrix(C,file,"Sheet",'Sheet3');
writematrix(D,file,"Sheet",'Sheet4');

% 标题行和相关参数
TitleRow1 = {'参数','数值','单位','η','k1','k-1','k2','k-2','k3','k-3','k4','k-4',...
            'Θ-OMV','Θ-OMOH','ΘOMO','ΘOMOOH','r1','r2','r3','r4','log(r)'};
TitleRow2 = {'参数','数值','单位','pH','k1','k-1','k2','k-2','k3','k-3','k4','k-4',...
            'Θ-OMV','Θ-OMOH','ΘOMO','ΘOMOOH','r1','r2','r3','r4','log(r)'};
CanShuNameOP = {'kBT/h','kBT','T','f','Ea0','γ','β','pH','ΔG1','ΔG2','ΔG3','ΔG4',...
            'λ1','λ2','λ3','λ4'}';
CanShuNamepH = {'kBT/h','kBT','T','f','Ea0','γ','β','η','ΔG1','ΔG2','ΔG3','ΔG4',...
            'λ1','λ2','λ3','λ4'}';
CanShuData = [6220889369003.16;0.0256925694830201;298.15;38.9217590969594];
CanShuUnit = {'s-1','eV','K','V-1','eV','','','','eV','eV','eV','eV','eV','eV','eV','eV'}';

% 写入随变化表格参数
writecell(TitleRow1,file,"Sheet",1,'Range','A1');
writecell(CanShuNameOP,file,"Sheet",1,'Range','A2');
writematrix(CanShuData,file,"Sheet",1,'Range','B2');
writecell(CanShuUnit,file,"Sheet",1,'Range','C2');
% 写入随pH变化表格参数
writecell(TitleRow2,file,"Sheet",3,'Range','A1');
writecell(CanShuNamepH,file,"Sheet",3,'Range','A2');
writematrix(CanShuData,file,"Sheet",3,'Range','B2');
writecell(CanShuUnit,file,"Sheet",3,'Range','C2');


%% 获取参数并运行
filename = 'ERLOM.xlsx';
yita = (app.OPdown.Value:app.OPstep.Value:app.OPup.Value)';
pH = (app.pHdown.Value:app.pHstep.Value:app.pHup.Value)';
writematrix(app.G1EditField.Value,filename,'Sheet',1, 'Range', 'B10');
writematrix(app.G2EditField.Value,filename,'Sheet',1, 'Range', 'B11');
writematrix(app.G3EditField.Value,filename,'Sheet',1, 'Range', 'B12');
writematrix(app.G4EditField.Value,filename,'Sheet',1, 'Range', 'B13');
writematrix(app.G1EditField.Value,filename,'Sheet',3, 'Range', 'B10');
writematrix(app.G2EditField.Value,filename,'Sheet',3, 'Range', 'B11');
writematrix(app.G3EditField.Value,filename,'Sheet',3, 'Range', 'B12');
writematrix(app.G4EditField.Value,filename,'Sheet',3, 'Range', 'B13');

writematrix(yita,filename,'Sheet', 1, 'Range', 'D2');
writematrix(pH,filename,'Sheet', 3, 'Range', 'D2');
writematrix(app.pHding.Value,filename,'Sheet',1, 'Range', 'B9');
writematrix(app.yitading.Value,filename,'Sheet',3, 'Range', 'B9');

writematrix(app.Ea0.Value,filename,'Sheet', 1 , 'Range', 'B6');
writematrix(app.gama.Value,filename,'Sheet', 1 , 'Range', 'B7');
writematrix(app.beita.Value,filename,'Sheet', 1 , 'Range', 'B8');
writematrix(app.Ea0.Value,filename,'Sheet', 3 , 'Range', 'B6');
writematrix(app.gama.Value,filename,'Sheet', 3 , 'Range', 'B7');
writematrix(app.beita.Value,filename,'Sheet', 3 , 'Range', 'B8');
run("ERLOM_BV.m");
t1 = readtable("ERLOM.xlsx",'VariableNamingRule', 'preserve',"Sheet",1,"Range",'D:U');
t2 = readtable("ERLOM.xlsx",'VariableNamingRule', 'preserve',"Sheet",3,"Range",'D:U');
app.tableOP.Data = t1;
app.tablepH.Data = t2;

% 获取η数据作为x轴
x1 = table2array(t1(:,1));
% 获取log(r)作为y轴
y1 = table2array(t1(:,18));
% 获取pH数据作为x轴
x2 = table2array(t2(:,1));
% 获取log(r)作为y轴
y2 = table2array(t2(:,18));

% 获取覆盖度数值作为y轴
y3 = table2array(t1(:,10));
y4 = table2array(t1(:,11));
y5 = table2array(t1(:,12));
y6 = table2array(t1(:,13));
y7 = table2array(t2(:,10));
y8 = table2array(t2(:,11));
y9 = table2array(t2(:,12));
y10 = table2array(t2(:,13));

% 清除异常数据 
y1max = 200;
y2max = 200;
valid_indices1 = y1 < y1max;  % 生成逻辑索引 
valid_indices2 = y2 < y2max;  % 生成逻辑索引 
x1_clean = x1(valid_indices1);
y1_clean = y1(valid_indices1);
x2_clean = x2(valid_indices2);
y2_clean = y2(valid_indices2);

% 清除坐标轴并绘制过电势η图形 
cla(app.picOP);
plot(app.picOP, x1_clean, y1_clean);
cla(app.picOPc);
plot(app.picOPc,x1,y3,'r',x1,y4,'b',x1,y5,'g',x1,y6,'y');
% 清除坐标轴并绘制酸碱度pH图形 
cla(app.picpH);
plot(app.picpH, x2_clean, y2_clean);
cla(app.picpHc);
plot(app.picpHc,x2,y7,'r',x2,y8,'b',x2,y9,'g',x2,y10,'y');


% 添加图例 
legend(app.picOPc,'θOMV', 'θOMOH', 'θOMO', 'θOMOOH');
legend(app.picpHc,'θOMV', 'θOMOH', 'θOMO', 'θOMOOH');


% 添加坐标轴标签 
app.picOP.XLabel.String = 'η';
app.picOP.YLabel.String = 'log(r)';
app.picOP.Title.String = 'η-log(r)';
app.picOPc.XLabel.String = 'η';
app.picOPc.YLabel.String = 'θ';
app.picOPc.Title.String = 'η-θ';
app.picpH.XLabel.String = 'pH';
app.picpH.YLabel.String = 'log(r)';
app.picpH.Title.String = 'pH-log(r)';
app.picpHc.XLabel.String = 'pH';
app.picpHc.YLabel.String = 'θ';
app.picpHc.Title.String = 'pH-θ';
        end

        % Button pushed function: M_OPButton
        function M_OPButtonPushed(app, event)
%% 创建ERLOM数据输出Excel表格文件
filename = 'ERLOM.xlsx';  % 指定要删除的文件名 
if exist("ERLOM.xlsx", 'file') == 2 % 检查文件是否存在 
    delete(filename); % 删除文件 
end 
% 创建空白表格
file = 'ERLOM.xlsx'; 
A = []; B = []; C =[]; D = [];
writematrix(A,file,"Sheet",'Sheet1');
writematrix(B,file,"Sheet",'Sheet2');
writematrix(C,file,"Sheet",'Sheet3');
writematrix(D,file,"Sheet",'Sheet4');

% 标题行和相关参数
TitleRow1 = {'参数','数值','单位','η','k1','k-1','k2','k-2','k3','k-3','k4','k-4',...
            'Θ-OMV','Θ-OMOH','ΘOMO','ΘOMOOH','r1','r2','r3','r4','log(r)'};
TitleRow2 = {'参数','数值','单位','pH','k1','k-1','k2','k-2','k3','k-3','k4','k-4',...
            'Θ-OMV','Θ-OMOH','ΘOMO','ΘOMOOH','r1','r2','r3','r4','log(r)'};
CanShuNameOP = {'kBT/h','kBT','T','f','Ea0','γ','β','pH','ΔG1','ΔG2','ΔG3','ΔG4',...
            'λ1','λ2','λ3','λ4'}';
CanShuNamepH = {'kBT/h','kBT','T','f','Ea0','γ','β','η','ΔG1','ΔG2','ΔG3','ΔG4',...
            'λ1','λ2','λ3','λ4'}';
CanShuData = [6220889369003.16;0.0256925694830201;298.15;38.9217590969594];
CanShuUnit = {'s-1','eV','K','V-1','eV','','','','eV','eV','eV','eV','eV','eV','eV','eV'}';

% 写入随变化表格参数
writecell(TitleRow1,file,"Sheet",1,'Range','A1');
writecell(CanShuNameOP,file,"Sheet",1,'Range','A2');
writematrix(CanShuData,file,"Sheet",1,'Range','B2');
writecell(CanShuUnit,file,"Sheet",1,'Range','C2');
% 写入随pH变化表格参数
writecell(TitleRow2,file,"Sheet",3,'Range','A1');
writecell(CanShuNamepH,file,"Sheet",3,'Range','A2');
writematrix(CanShuData,file,"Sheet",3,'Range','B2');
writecell(CanShuUnit,file,"Sheet",3,'Range','C2');

%% 获取参数并运行
filename = 'ERLOM.xlsx';
yita = (app.OPdown.Value:app.OPstep.Value:app.OPup.Value)';
pH = (app.pHdown.Value:app.pHstep.Value:app.pHup.Value)';

writematrix(app.G1EditField.Value,filename,'Sheet',1, 'Range', 'B10');
writematrix(app.G2EditField.Value,filename,'Sheet',1, 'Range', 'B11');
writematrix(app.G3EditField.Value,filename,'Sheet',1, 'Range', 'B12');
writematrix(app.G4EditField.Value,filename,'Sheet',1, 'Range', 'B13');
writematrix(app.G1EditField.Value,filename,'Sheet',3, 'Range', 'B10');
writematrix(app.G2EditField.Value,filename,'Sheet',3, 'Range', 'B11');
writematrix(app.G3EditField.Value,filename,'Sheet',3, 'Range', 'B12');
writematrix(app.G4EditField.Value,filename,'Sheet',3, 'Range', 'B13');

writematrix(app.lanmuda1.Value,filename,'Sheet', 1 , 'Range', 'B14');
writematrix(app.lanmuda2.Value,filename,'Sheet', 1 , 'Range', 'B15');
writematrix(app.lanmuda3.Value,filename,'Sheet', 1 , 'Range', 'B16');
writematrix(app.lanmuda4.Value,filename,'Sheet', 1 , 'Range', 'B17');
writematrix(app.lanmuda1.Value,filename,'Sheet', 3 , 'Range', 'B14');
writematrix(app.lanmuda2.Value,filename,'Sheet', 3 , 'Range', 'B15');
writematrix(app.lanmuda3.Value,filename,'Sheet', 3 , 'Range', 'B16');
writematrix(app.lanmuda4.Value,filename,'Sheet', 3 , 'Range', 'B17');

writematrix(yita,filename,'Sheet', 1, 'Range', 'D2');
writematrix(pH,filename,'Sheet', 3, 'Range', 'D2');
writematrix(app.pHding.Value,filename,'Sheet',1, 'Range', 'B9');
writematrix(app.yitading.Value,filename,'Sheet',3, 'Range', 'B9');

run("ERLOM_M.m");
t1 = readtable("ERLOM.xlsx",'VariableNamingRule', 'preserve',"Sheet",1,"Range",'D:U');
t2 = readtable("ERLOM.xlsx",'VariableNamingRule', 'preserve',"Sheet",3,"Range",'D:U');
app.tableOP.Data = t1;
app.tablepH.Data = t2;

% 获取η数据作为x轴
x1 = table2array(t1(:,1));
% 获取log(r)作为y轴
y1 = table2array(t1(:,18));
% 获取pH数据作为x轴
x2 = table2array(t2(:,1));
% 获取log(r)作为y轴
y2 = table2array(t2(:,18));

% 获取覆盖度数值作为y轴
y3 = table2array(t1(:,10));
y4 = table2array(t1(:,11));
y5 = table2array(t1(:,12));
y6 = table2array(t1(:,13));
y7 = table2array(t2(:,10));
y8 = table2array(t2(:,11));
y9 = table2array(t2(:,12));
y10 = table2array(t2(:,13));

% 清除异常数据 
y1max = 200;
y2max = 200;
valid_indices1 = y1 < y1max;  % 生成逻辑索引 
valid_indices2 = y2 < y2max;  % 生成逻辑索引 
x1_clean = x1(valid_indices1);
y1_clean = y1(valid_indices1);
x2_clean = x2(valid_indices2);
y2_clean = y2(valid_indices2);

% 清除坐标轴并绘制过电势η图形 
cla(app.picOP);
plot(app.picOP, x1_clean, y1_clean);
cla(app.picOPc);
plot(app.picOPc,x1,y3,'r',x1,y4,'b',x1,y5,'g',x1,y6,'y');
% 清除坐标轴并绘制酸碱度pH图形 
cla(app.picpH);
plot(app.picpH, x2_clean, y2_clean);
cla(app.picpHc);
plot(app.picpHc,x2,y7,'r',x2,y8,'b',x2,y9,'g',x2,y10,'y');


% 添加图例 
legend(app.picOPc,'θOMV', 'θOMOH', 'θOMO', 'θOMOOH');
legend(app.picpHc,'θOMV', 'θOMOH', 'θOMO', 'θOMOOH');


% 添加坐标轴标签 
app.picOP.XLabel.String = 'η';
app.picOP.YLabel.String = 'log(r)';
app.picOP.Title.String = 'η-log(r)';
app.picOPc.XLabel.String = 'η';
app.picOPc.YLabel.String = 'θ';
app.picOPc.Title.String = 'η-θ';
app.picpH.XLabel.String = 'pH';
app.picpH.YLabel.String = 'log(r)';
app.picpH.Title.String = 'pH-log(r)';
app.picpHc.XLabel.String = 'pH';
app.picpHc.YLabel.String = 'θ';
app.picpHc.Title.String = 'pH-θ';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LeftPanel = uipanel(app.UIFigure);
            app.Label_16 = uilabel(app.LeftPanel);
            app.Label_16.Text = '';
            app.Label_15 = uilabel(app.LeftPanel);
            app.Label_15.Text = '';
            app.Label_14 = uilabel(app.LeftPanel);
            app.Label_14.Text = '';
            app.Label_13 = uilabel(app.LeftPanel);
            app.Label_13.Text = '';
            app.ERLOMLabel = uilabel(app.LeftPanel);
            app.ERLOMLabel.Text = 'ERLOM';
            app.G1EditFieldLabel = uilabel(app.LeftPanel);
            app.G1EditFieldLabel.Text = 'G1';
            app.G1EditField = uieditfield(app.LeftPanel, 'numeric');
            app.G2EditFieldLabel = uilabel(app.LeftPanel);
            app.G2EditFieldLabel.Text = 'G2';
            app.G2EditField = uieditfield(app.LeftPanel, 'numeric');
            app.G3EditFieldLabel = uilabel(app.LeftPanel);
            app.G3EditFieldLabel.Text = 'G3';
            app.G3EditField = uieditfield(app.LeftPanel, 'numeric');
            app.G4EditFieldLabel = uilabel(app.LeftPanel);
            app.G4EditFieldLabel.Text = 'G4';
            app.G4EditField = uieditfield(app.LeftPanel, 'numeric');
            app.EditFieldLabel = uilabel(app.LeftPanel);
            app.EditFieldLabel.Text = 'λ1';
            app.lanmuda1 = uieditfield(app.LeftPanel, 'numeric');
            app.lanmuda1.Value = 2;
            app.EditField_2Label = uilabel(app.LeftPanel);
            app.EditField_2Label.Text = 'λ2';
            app.lanmuda2 = uieditfield(app.LeftPanel, 'numeric');
            app.lanmuda2.Value = 2;
            app.EditField_3Label = uilabel(app.LeftPanel);
            app.EditField_3Label.Text = 'λ3';
            app.lanmuda3 = uieditfield(app.LeftPanel, 'numeric');
            app.lanmuda3.Value = 2;
            app.EditField_4Label = uilabel(app.LeftPanel);
            app.EditField_4Label.Text = 'λ4';
            app.lanmuda4 = uieditfield(app.LeftPanel, 'numeric');
            app.lanmuda4.Value = 2;
            app.MG_OPButton = uibutton(app.LeftPanel, 'push');
            app.MG_OPButton.ButtonPushedFcn = createCallbackFcn(app, @MG_OPButtonPushed, true);
            app.MG_OPButton.Text = 'MG';
            app.Label = uilabel(app.LeftPanel);
            app.Label.Text = '上限';
            app.OPup = uieditfield(app.LeftPanel, 'numeric');
            app.OPup.Value = 1;
            app.EditField_3Label_2 = uilabel(app.LeftPanel);
            app.EditField_3Label_2.Text = '步长';
            app.OPstep = uieditfield(app.LeftPanel, 'numeric');
            app.OPstep.Value = 0.01;
            app.EditField_2Label_2 = uilabel(app.LeftPanel);
            app.EditField_2Label_2.Text = '下限';
            app.OPdown = uieditfield(app.LeftPanel, 'numeric');
            app.OPdown.Value = -1;
            app.Label_2 = uilabel(app.LeftPanel);
            app.Label_2.Text = 'η';
            app.pHEditFieldLabel = uilabel(app.LeftPanel);
            app.pHEditFieldLabel.Text = 'pH';
            app.pHding = uieditfield(app.LeftPanel, 'numeric');
            app.Ea0Label = uilabel(app.LeftPanel);
            app.Ea0Label.Text = 'Ea,0';
            app.Ea0 = uieditfield(app.LeftPanel, 'numeric');
            app.Ea0.Value = 0.5;
            app.Label_6 = uilabel(app.LeftPanel);
            app.Label_6.Text = 'β';
            app.beita = uieditfield(app.LeftPanel, 'numeric');
            app.beita.Value = 0.5;
            app.Label_7 = uilabel(app.LeftPanel);
            app.Label_7.Text = 'γ';
            app.gama = uieditfield(app.LeftPanel, 'numeric');
            app.gama.Value = 0.5;
            app.ButlerVolmerkineticsLabel = uilabel(app.LeftPanel);
            app.ButlerVolmerkineticsLabel.Text = 'Butler-Volmer kinetics';
            app.MarcusMGkineticsLabel = uilabel(app.LeftPanel);
            app.MarcusMGkineticsLabel.Text = 'Marcus & M-G kinetics';
            app.BV_OPButton = uibutton(app.LeftPanel, 'push');
            app.BV_OPButton.ButtonPushedFcn = createCallbackFcn(app, @BV_OPButtonPushed, true);
            app.BV_OPButton.Text = 'BV';
            app.M_OPButton = uibutton(app.LeftPanel, 'push');
            app.M_OPButton.ButtonPushedFcn = createCallbackFcn(app, @M_OPButtonPushed, true);
            app.M_OPButton.Text = 'M';
            app.Label_9 = uilabel(app.LeftPanel);
            app.Label_9.Text = '通用参数';
            app.Label_11 = uilabel(app.LeftPanel);
            app.Label_11.Text = 'η';
            app.yitading = uieditfield(app.LeftPanel, 'numeric');
            app.EditFieldLabel_2 = uilabel(app.LeftPanel);
            app.EditFieldLabel_2.Text = '上限';
            app.pHup = uieditfield(app.LeftPanel, 'numeric');
            app.pHup.Value = 14;
            app.EditField_3Label_3 = uilabel(app.LeftPanel);
            app.EditField_3Label_3.Text = '下限';
            app.pHdown = uieditfield(app.LeftPanel, 'numeric');
            app.EditField_4Label_2 = uilabel(app.LeftPanel);
            app.EditField_4Label_2.Text = '步长';
            app.pHstep = uieditfield(app.LeftPanel, 'numeric');
            app.pHstep.Value = 0.1;
            app.pHLabel = uilabel(app.LeftPanel);
            app.pHLabel.Text = 'pH';
            app.Label_17 = uilabel(app.LeftPanel);
            app.Label_17.Text = '参数范围';
            app.CenterPanel = uipanel(app.UIFigure);
            app.picOP = uiaxes(app.CenterPanel);
            title(app.picOP, 'Title')
            xlabel(app.picOP, 'X')
            ylabel(app.picOP, 'Y')
            zlabel(app.picOP, 'Z')
            app.picOPc = uiaxes(app.CenterPanel);
            title(app.picOPc, 'Title')
            xlabel(app.picOPc, 'X')
            ylabel(app.picOPc, 'Y')
            zlabel(app.picOPc, 'Z')
            app.tableOP = uitable(app.CenterPanel);
            app.tableOP.ColumnName = {'η'; 'k1'; 'k-1'; 'k2'; 'k-2'; 'k3'; 'k-3'; 'k4'; 'k-4'; 'OMV'; 'OMOH'; 'OMO'; 'OMOOH'; 'r1'; 'r2'; 'r3'; 'r4'; 'log(r)'};
            app.tableOP.RowName = {};
            app.RightPanel = uipanel(app.UIFigure);
            app.picpH = uiaxes(app.RightPanel);
            title(app.picpH, 'Title')
            xlabel(app.picpH, 'X')
            ylabel(app.picpH, 'Y')
            zlabel(app.picpH, 'Z')
            app.picpHc = uiaxes(app.RightPanel);
            title(app.picpHc, 'Title')
            xlabel(app.picpHc, 'X')
            ylabel(app.picpHc, 'Y')
            zlabel(app.picpHc, 'Z')
            app.tablepH = uitable(app.RightPanel);
            app.tablepH.ColumnName = {'pH'; 'k1'; 'k-1'; 'k2'; 'k-2'; 'k3'; 'k-3'; 'k4'; 'k-4'; 'OMV'; 'OMOH'; 'OMO'; 'OMOOH'; 'r1'; 'r2'; 'r3'; 'r4'; 'log(r)'};
            app.tablepH.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ERLOM

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end