classdef LHLOM < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        RightPanel                 matlab.ui.container.Panel
        pHtable                    matlab.ui.control.Table
        pHpic3                     matlab.ui.control.UIAxes
        pHpic2                     matlab.ui.control.UIAxes
        pHpic1                     matlab.ui.control.UIAxes
        CenterPanel                matlab.ui.container.Panel
        OPtable                    matlab.ui.control.Table
        OPpic3                     matlab.ui.control.UIAxes
        OPpic2                     matlab.ui.control.UIAxes
        OPpic1                     matlab.ui.control.UIAxes
        LeftPanel                  matlab.ui.container.Panel
        Label1_4                   matlab.ui.control.Label
        pHstep                     matlab.ui.control.NumericEditField
        EditField_4Label_2         matlab.ui.control.Label
        pHdown                     matlab.ui.control.NumericEditField
        EditField_3Label_3         matlab.ui.control.Label
        pHup                       matlab.ui.control.NumericEditField
        Label_17                   matlab.ui.control.Label
        pHLabel                    matlab.ui.control.Label
        yitading                   matlab.ui.control.NumericEditField
        Label_16                   matlab.ui.control.Label
        Label1_3                   matlab.ui.control.Label
        G1                         matlab.ui.control.NumericEditField
        G2_1                       matlab.ui.control.NumericEditField
        G2_2                       matlab.ui.control.NumericEditField
        G3_1                       matlab.ui.control.NumericEditField
        G3_2                       matlab.ui.control.NumericEditField
        G4                         matlab.ui.control.NumericEditField
        G5                         matlab.ui.control.NumericEditField
        lanmuda1                   matlab.ui.control.NumericEditField
        lanmuda2_1                 matlab.ui.control.NumericEditField
        lanmuda2_2                 matlab.ui.control.NumericEditField
        lanmuda3_1                 matlab.ui.control.NumericEditField
        lanmuda3_2                 matlab.ui.control.NumericEditField
        lanmuda4                   matlab.ui.control.NumericEditField
        EditField_4Label           matlab.ui.control.Label
        Label_14                   matlab.ui.control.Label
        G5EditFieldLabel           matlab.ui.control.Label
        G4EditFieldLabel           matlab.ui.control.Label
        G3_2EditFieldLabel         matlab.ui.control.Label
        LHLOMLabel                 matlab.ui.control.Label
        ButlerVolmerkineticsLabel  matlab.ui.control.Label
        MarcusMarcusGerischerkineticsLabel  matlab.ui.control.Label
        BVButton                   matlab.ui.control.Button
        MButton                    matlab.ui.control.Button
        MGButton                   matlab.ui.control.Button
        gama                       matlab.ui.control.NumericEditField
        Label_7                    matlab.ui.control.Label
        beita                      matlab.ui.control.NumericEditField
        Label_6                    matlab.ui.control.Label
        Ea0                        matlab.ui.control.NumericEditField
        Ea0Label                   matlab.ui.control.Label
        pHding                     matlab.ui.control.NumericEditField
        pHEditFieldLabel           matlab.ui.control.Label
        yitaLabel                  matlab.ui.control.Label
        up                         matlab.ui.control.NumericEditField
        down                       matlab.ui.control.NumericEditField
        EditField_2Label_2         matlab.ui.control.Label
        step                       matlab.ui.control.NumericEditField
        EditField_3Label_2         matlab.ui.control.Label
        Label                      matlab.ui.control.Label
        Label_13                   matlab.ui.control.Label
        Label_12                   matlab.ui.control.Label
        Label_11                   matlab.ui.control.Label
        EditFieldLabel             matlab.ui.control.Label
        G3_1EditFieldLabel         matlab.ui.control.Label
        G2_2EditFieldLabel         matlab.ui.control.Label
        G2_1EditFieldLabel         matlab.ui.control.Label
        G1EditFieldLabel           matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: MGButton
        function MGButtonPushed(app, event)
%% 创建LHLOM数据输出Excel表格文件
filename = 'LHLOM.xlsx';  % 指定要删除的文件名 
if exist("LHLOM.xlsx", 'file') == 2 % 检查文件是否存在 
    delete(filename); % 删除文件 
end 
% 创建空白表格
file = 'LHLOM.xlsx'; 
A = []; B = [];C = []; D = [];
writematrix(A,file,"Sheet",'Sheet1');writematrix(B,file,"Sheet",'Sheet2');
writematrix(C,file,"Sheet",'Sheet3');writematrix(D,file,"Sheet",'Sheet4');
% 写入标题行和相关参数名称
TitleRow1 = {'参数','数值','单位','η',...
'k1','k-1','k2-1','k-2-1','k2-2','k-2-2','k3-1','k-3-1','k3-2','k-3-2','k4','k-4','k5','k-5',...
'ΘVMV','ΘOHMV','ΘOHMOH','ΘOMV','ΘOMOH','ΘOMO',...
'r1','r2-1','r2-2','r3-1','r3-2','r4','r5','log(r1)'};
TitleRow2 = {'参数','数值','单位','pH',...
'k1','k-1','k2-1','k-2-1','k2-2','k-2-2','k3-1','k-3-1','k3-2','k-3-2','k4','k-4','k5','k-5',...
'ΘVMV','ΘOHMV','ΘOHMOH','ΘOMV','ΘOMOH','ΘOMO',...
'r1','r2-1','r2-2','r3-1','r3-2','r4','r5','log(r1)'};
CanShuName1 = {'kBT/h','kBT','T','f','Ea0','γ','β','pH','ΔG1','ΔG2_1','ΔG2_2','ΔG3_1',...
'ΔG3_2','ΔG4','ΔG5','λ1','λ2-1','λ2-2','λ3-1','λ3-2','λ4'}';
CanShuName2 = {'kBT/h','kBT','T','f','Ea0','γ','β','η','ΔG1','ΔG2_1','ΔG2_2','ΔG3_1',...
'ΔG3_2','ΔG4','ΔG5','λ1','λ2-1','λ2-2','λ3-1','λ3-2','λ4'}';
CanShuData = [6220889369003.16;0.0256925694830201;298.15;38.9217590969594];
CanShuUnit = {'s-1','eV','K','V-1','eV','','','','eV','eV','eV','eV','eV','eV','eV','eV','eV','eV','eV','eV','eV'}';
% 构建参数接收表格Shee1
writecell(TitleRow1,file,"Sheet",1,'Range','A1');writecell(CanShuName1,file,"Sheet",1,'Range','A2');
writematrix(CanShuData,file,"Sheet",1,'Range','B2');writecell(CanShuUnit,file,"Sheet",1,'Range','C2');
% 构建参数接收表格Shee3
writecell(TitleRow2,file,"Sheet",3,'Range','A1');writecell(CanShuName2,file,"Sheet",3,'Range','A2');
writematrix(CanShuData,file,"Sheet",3,'Range','B2');writecell(CanShuUnit,file,"Sheet",3,'Range','C2');

%% 获取参数并运算
filename = 'LHLOM.xlsx';
yita = (app.down.Value:app.step.Value:app.up.Value)';
pH = (app.pHdown.Value:app.pHstep.Value:app.pHup.Value)';
% 在Excel表格中写入用户输入的参数
writematrix(app.G1.Value,filename,'Sheet',1 , 'Range', 'B10');writematrix(app.G2_1.Value,filename,'Sheet',1, 'Range', 'B11');
writematrix(app.G2_2.Value,filename,'Sheet',1, 'Range', 'B12');writematrix(app.G3_1.Value,filename,'Sheet',1, 'Range', 'B13');
writematrix(app.G3_2.Value,filename,'Sheet',1, 'Range', 'B14');writematrix(app.G4.Value,filename,'Sheet',1, 'Range', 'B15');
writematrix(app.G5.Value,filename,'Sheet',1, 'Range', 'B16');
writematrix(app.G1.Value,filename,'Sheet',3 , 'Range', 'B10');writematrix(app.G2_1.Value,filename,'Sheet',3, 'Range', 'B11');
writematrix(app.G2_2.Value,filename,'Sheet',3, 'Range', 'B12');writematrix(app.G3_1.Value,filename,'Sheet',3, 'Range', 'B13');
writematrix(app.G3_2.Value,filename,'Sheet',3, 'Range', 'B14');writematrix(app.G4.Value,filename,'Sheet',3, 'Range', 'B15');
writematrix(app.G5.Value,filename,'Sheet',3, 'Range', 'B16');
writematrix(app.lanmuda1.Value,filename,'Sheet', 1 , 'Range', 'B17');writematrix(app.lanmuda2_1.Value,filename,'Sheet', 1 , 'Range', 'B18');
writematrix(app.lanmuda2_2.Value,filename,'Sheet', 1 , 'Range', 'B19');writematrix(app.lanmuda3_1.Value,filename,'Sheet', 1 , 'Range', 'B20');
writematrix(app.lanmuda3_2.Value,filename,'Sheet', 1 , 'Range', 'B21');writematrix(app.lanmuda4.Value,filename,'Sheet', 1 , 'Range', 'B22');
writematrix(app.lanmuda1.Value,filename,'Sheet', 3 , 'Range', 'B17');writematrix(app.lanmuda2_1.Value,filename,'Sheet', 3 , 'Range', 'B18');
writematrix(app.lanmuda2_2.Value,filename,'Sheet', 3 , 'Range', 'B19');writematrix(app.lanmuda3_1.Value,filename,'Sheet', 3 , 'Range', 'B20');
writematrix(app.lanmuda3_2.Value,filename,'Sheet', 3 , 'Range', 'B21');writematrix(app.lanmuda4.Value,filename,'Sheet', 3 , 'Range', 'B22');
writematrix(yita,filename,'Sheet', 1, 'Range', 'D2');writematrix(pH,filename,'Sheet', 3, 'Range', 'D2');
writematrix(app.pHding.Value,filename,'Sheet',1, 'Range', 'B9');writematrix(app.yitading.Value,filename,'Sheet',3, 'Range', 'B9');
writematrix(app.Ea0.Value,filename,'Sheet', 1 , 'Range', 'B6');writematrix(app.gama.Value,filename,'Sheet', 1 , 'Range', 'B7');
writematrix(app.beita.Value,filename,'Sheet', 1 , 'Range', 'B8');writematrix(app.Ea0.Value,filename,'Sheet', 3 , 'Range', 'B6');
writematrix(app.gama.Value,filename,'Sheet', 3 , 'Range', 'B7');writematrix(app.beita.Value,filename,'Sheet', 3 , 'Range', 'B8');
% 运行按钮对应方法的脚本
run("LHLOM_MG.m");

% 读取Excel表格计算后的数据
t1 = readtable("LHLOM.xlsx",'VariableNamingRule', 'preserve',"Sheet",1,"Range",'D:AH');
t2 = readtable("LHLOM.xlsx",'VariableNamingRule', 'preserve',"Sheet",3,"Range",'D:AH');
app.OPtable.Data = t1;app.pHtable.Data = t2;
% η部分——图1的参数
x1 = table2array(t1(:,1));
log1 = table2array(t1(:,29));
% η部分——图2纵坐标的参数
y3 = table2array(t1(:,16));y4 = table2array(t1(:,17));y5 = table2array(t1(:,18));
y6 = table2array(t1(:,19));y7 = table2array(t1(:,20));y8 = table2array(t1(:,21));
% η部分——图3纵坐标的参数
ro1 = table2array(t1(:,22));ro2_1 = table2array(t1(:,23));ro2_2 = table2array(t1(:,24));
ro3_1 = table2array(t1(:,25));ro3_2 = table2array(t1(:,26));ro4 = table2array(t1(:,27));
ro5 = table2array(t1(:,28));
% pH部分——图1的参数
x2 = table2array(t2(:,1));
log2 = table2array(t2(:,29));
% pH部分——图2纵坐标的参数
y9 = table2array(t2(:,16));y10 = table2array(t2(:,17));y11 = table2array(t2(:,18));
y12 = table2array(t2(:,19));y13 = table2array(t2(:,20));y14 = table2array(t2(:,21));
% pH部分——图3纵坐标的参数
rp1 = table2array(t2(:,22));rp2_1 = table2array(t2(:,23));rp2_2 = table2array(t2(:,24));
rp3_1 = table2array(t2(:,25));rp3_2 = table2array(t2(:,26));rp4 = table2array(t2(:,27));
rp5 = table2array(t2(:,28));

% 清除异常数据 
max = 200;
valid_indices1 = log1 < max;  % 生成逻辑索引 
valid_indices2 = log2 < max;  % 生成逻辑索引 
x1_clean = x1(valid_indices1);
y1_clean = log1(valid_indices1);
x2_clean = x2(valid_indices2);
y2_clean = log2(valid_indices2);

% 清除坐标轴并绘制η部分图1
cla(app.OPpic1);
plot(app.OPpic1, x1_clean, y1_clean);
% 清除坐标轴并绘制η部分图2
cla(app.OPpic2);
plot(app.OPpic2, x1, y3,'r',x1, y4,'b',x1,y5,'g',x1,y6,'k',x1,y7,'y',x1,y8,'m');
legend(app.OPpic2,'VMV','OHMV','OHMOH','OMV','OMOH','OMO');
% 清除坐标轴并绘制η部分图3
cla(app.OPpic3);
plot(app.OPpic3,x1,ro1,'r',x1, ro2_1,'b',x1,ro3_1,'g',x1,ro2_2,'k',x1,ro3_2,'y',x1,ro4,'m',x1,ro5,'c');
legend(app.OPpic3,'r1','r2-1','r3-1','r2-2','r3-2','r4','r5');
% 清除坐标轴并绘制pH部分图1 
cla(app.pHpic1);
plot(app.pHpic1, x2_clean,y2_clean);
% 清除坐标轴并绘制pH部分图2
cla(app.pHpic2);
plot(app.pHpic2, x2, y9,'r',x2,y10,'b',x2,y11,'g',x2,y12,'k',x2,y13,'y',x2,y14,'m');
legend(app.pHpic2,'VMV','OHMV','OHMOH','OMV','OMOH','OMO');
% 清除坐标轴并绘制pH部分图3
cla(app.pHpic3);
plot(app.pHpic3,x2,rp1,'r',x2, rp2_1,'b',x2,rp3_1,'g',x2,rp2_2,'k',x2,rp3_2,'y',x2,rp4,'m',x2,rp5,'c');
legend(app.pHpic3,'r1','r2-1','r3-1','r2-2','r3-2','r4','r5');

% 添加坐标轴标签 
app.OPpic1.XLabel.String = 'η';app.OPpic1.YLabel.String = 'log(r)';app.OPpic1.Title.String = 'η-log(r)';
app.OPpic2.XLabel.String = 'η';app.OPpic2.YLabel.String = 'θ';app.OPpic2.Title.String = 'η-θ';
app.OPpic3.XLabel.String = 'η';app.OPpic3.YLabel.String = 'r';app.OPpic3.Title.String = 'η-r';
app.pHpic1.XLabel.String = 'pH';app.pHpic1.YLabel.String = 'log(r)';app.pHpic1.Title.String = 'pH-log(r)';
app.pHpic2.XLabel.String = 'pH';app.pHpic2.YLabel.String = 'θ';app.pHpic2.Title.String = 'pH-θ';
app.pHpic3.XLabel.String = 'pH';app.pHpic3.YLabel.String = 'r';app.pHpic3.Title.String = 'pH-r';
        end

        % Button pushed function: BVButton
        function BVButtonPushed(app, event)
%% 创建LHLOM数据输出Excel表格文件
filename = 'LHLOM.xlsx';  % 指定要删除的文件名 
if exist("LHLOM.xlsx", 'file') == 2 % 检查文件是否存在 
    delete(filename); % 删除文件 
end 
% 创建空白表格
file = 'LHLOM.xlsx'; 
A = []; B = [];C = []; D = [];
writematrix(A,file,"Sheet",'Sheet1');
writematrix(B,file,"Sheet",'Sheet2');
writematrix(C,file,"Sheet",'Sheet3');
writematrix(D,file,"Sheet",'Sheet4');

% 写入标题行和相关参数
TitleRow1 = {'参数','数值','单位','η',...
'k1','k-1','k2-1','k-2-1','k2-2','k-2-2','k3-1','k-3-1','k3-2','k-3-2','k4','k-4','k5','k-5',...
'ΘVMV','ΘOHMV','ΘOHMOH','ΘOMV','ΘOMOH','ΘOMO',...
'r1','r2-1','r2-2','r3-1','r3-2','r4','r5','log(r1)'};
TitleRow2 = {'参数','数值','单位','pH',...
'k1','k-1','k2-1','k-2-1','k2-2','k-2-2','k3-1','k-3-1','k3-2','k-3-2','k4','k-4','k5','k-5',...
'ΘVMV','ΘOHMV','ΘOHMOH','ΘOMV','ΘOMOH','ΘOMO',...
'r1','r2-1','r2-2','r3-1','r3-2','r4','r5','log(r1)'};
CanShuName1 = {'kBT/h','kBT','T','f','Ea0','γ','β','pH','ΔG1','ΔG2_1','ΔG2_2','ΔG3_1',...
'ΔG3_2','ΔG4','ΔG5','λ1','λ2-1','λ2-2','λ3-1','λ3-2','λ4'}';
CanShuName2 = {'kBT/h','kBT','T','f','Ea0','γ','β','η','ΔG1','ΔG2_1','ΔG2_2','ΔG3_1',...
'ΔG3_2','ΔG4','ΔG5','λ1','λ2-1','λ2-2','λ3-1','λ3-2','λ4'}';
CanShuData = [6220889369003.16;0.0256925694830201;298.15;38.9217590969594];
CanShuUnit = {'s-1','eV','K','V-1','eV','','','','eV','eV','eV','eV','eV','eV','eV','eV','eV','eV','eV','eV','eV'}';
% 构建Shee1
writecell(TitleRow1,file,"Sheet",1,'Range','A1');
writecell(CanShuName1,file,"Sheet",1,'Range','A2');
writematrix(CanShuData,file,"Sheet",1,'Range','B2');
writecell(CanShuUnit,file,"Sheet",1,'Range','C2');
% 构建Shee3
writecell(TitleRow2,file,"Sheet",3,'Range','A1');
writecell(CanShuName2,file,"Sheet",3,'Range','A2');
writematrix(CanShuData,file,"Sheet",3,'Range','B2');
writecell(CanShuUnit,file,"Sheet",3,'Range','C2');

%% 获取参数并运算
filename = 'LHLOM.xlsx';
yita = (app.down.Value:app.step.Value:app.up.Value)';
pH = (app.pHdown.Value:app.pHstep.Value:app.pHup.Value)';
writematrix(app.G1.Value,filename,'Sheet',1 , 'Range', 'B10');
writematrix(app.G2_1.Value,filename,'Sheet',1, 'Range', 'B11');
writematrix(app.G2_2.Value,filename,'Sheet',1, 'Range', 'B12');
writematrix(app.G3_1.Value,filename,'Sheet',1, 'Range', 'B13');
writematrix(app.G3_2.Value,filename,'Sheet',1, 'Range', 'B14');
writematrix(app.G4.Value,filename,'Sheet',1, 'Range', 'B15');
writematrix(app.G5.Value,filename,'Sheet',1, 'Range', 'B16');
writematrix(app.G1.Value,filename,'Sheet',3 , 'Range', 'B10');
writematrix(app.G2_1.Value,filename,'Sheet',3, 'Range', 'B11');
writematrix(app.G2_2.Value,filename,'Sheet',3, 'Range', 'B12');
writematrix(app.G3_1.Value,filename,'Sheet',3, 'Range', 'B13');
writematrix(app.G3_2.Value,filename,'Sheet',3, 'Range', 'B14');
writematrix(app.G4.Value,filename,'Sheet',3, 'Range', 'B15');
writematrix(app.G5.Value,filename,'Sheet',3, 'Range', 'B16');

writematrix(yita,filename,'Sheet', 1, 'Range', 'D2');
writematrix(pH,filename,'Sheet', 3, 'Range', 'D2');
writematrix(app.pHding.Value,filename,'Sheet',1, 'Range', 'B9');
writematrix(app.yitading.Value,filename,'Sheet',3, 'Range', 'B9');

writematrix(app.Ea0.Value,filename,'Sheet', 1 , 'Range', 'B6');
writematrix(app.gama.Value,filename,'Sheet', 1 , 'Range', 'B7');
writematrix(app.beita.Value,filename,'Sheet', 1 , 'Range', 'B8');
writematrix(app.Ea0.Value,filename,'Sheet', 3 , 'Range', 'B6');
writematrix(app.gama.Value,filename,'Sheet', 3 , 'Range', 'B7');
writematrix(app.beita.Value,filename,'Sheet', 3 , 'Range', 'B8');
run("LHLOM_BV.m");
% 读取Excel表格计算后的数据
t1 = readtable("LHLOM.xlsx",'VariableNamingRule', 'preserve',"Sheet",1,"Range",'D:AH');
t2 = readtable("LHLOM.xlsx",'VariableNamingRule', 'preserve',"Sheet",3,"Range",'D:AH');
app.OPtable.Data = t1;app.pHtable.Data = t2;
% η部分——图1的参数
x1 = table2array(t1(:,1));
log1 = table2array(t1(:,29));
% η部分——图2纵坐标的参数
y3 = table2array(t1(:,16));y4 = table2array(t1(:,17));y5 = table2array(t1(:,18));
y6 = table2array(t1(:,19));y7 = table2array(t1(:,20));y8 = table2array(t1(:,21));
% η部分——图3纵坐标的参数
ro1 = table2array(t1(:,22));ro2_1 = table2array(t1(:,23));ro2_2 = table2array(t1(:,24));
ro3_1 = table2array(t1(:,25));ro3_2 = table2array(t1(:,26));ro4 = table2array(t1(:,27));
ro5 = table2array(t1(:,28));
% pH部分——图1的参数
x2 = table2array(t2(:,1));
log2 = table2array(t2(:,29));
% pH部分——图2纵坐标的参数
y9 = table2array(t2(:,16));y10 = table2array(t2(:,17));y11 = table2array(t2(:,18));
y12 = table2array(t2(:,19));y13 = table2array(t2(:,20));y14 = table2array(t2(:,21));
% pH部分——图3纵坐标的参数
rp1 = table2array(t2(:,22));rp2_1 = table2array(t2(:,23));rp2_2 = table2array(t2(:,24));
rp3_1 = table2array(t2(:,25));rp3_2 = table2array(t2(:,26));rp4 = table2array(t2(:,27));
rp5 = table2array(t2(:,28));

% 清除异常数据 
max = 200;
valid_indices1 = log1 < max;  % 生成逻辑索引 
valid_indices2 = log2 < max;  % 生成逻辑索引 
x1_clean = x1(valid_indices1);
y1_clean = log1(valid_indices1);
x2_clean = x2(valid_indices2);
y2_clean = log2(valid_indices2);

% 清除坐标轴并绘制η部分图1
cla(app.OPpic1);
plot(app.OPpic1, x1_clean, y1_clean);
% 清除坐标轴并绘制η部分图2
cla(app.OPpic2);
plot(app.OPpic2, x1, y3,'r',x1, y4,'b',x1,y5,'g',x1,y6,'k',x1,y7,'y',x1,y8,'m');
legend(app.OPpic2,'VMV','OHMV','OHMOH','OMV','OMOH','OMO');
% 清除坐标轴并绘制η部分图3
cla(app.OPpic3);
plot(app.OPpic3,x1,ro1,'r',x1, ro2_1,'b',x1,ro3_1,'g',x1,ro2_2,'k',x1,ro3_2,'y',x1,ro4,'m',x1,ro5,'c');
legend(app.OPpic3,'r1','r2-1','r3-1','r2-2','r3-2','r4','r5');
% 清除坐标轴并绘制pH部分图1 
cla(app.pHpic1);
plot(app.pHpic1, x2_clean,y2_clean);
% 清除坐标轴并绘制pH部分图2
cla(app.pHpic2);
plot(app.pHpic2, x2, y9,'r',x2,y10,'b',x2,y11,'g',x2,y12,'k',x2,y13,'y',x2,y14,'m');
legend(app.pHpic2,'VMV','OHMV','OHMOH','OMV','OMOH','OMO');
% 清除坐标轴并绘制pH部分图3
cla(app.pHpic3);
plot(app.pHpic3,x2,rp1,'r',x2, rp2_1,'b',x2,rp3_1,'g',x2,rp2_2,'k',x2,rp3_2,'y',x2,rp4,'m',x2,rp5,'c');
legend(app.pHpic3,'r1','r2-1','r3-1','r2-2','r3-2','r4','r5');

% 添加坐标轴标签 
app.OPpic1.XLabel.String = 'η';app.OPpic1.YLabel.String = 'log(r)';app.OPpic1.Title.String = 'η-log(r)';
app.OPpic2.XLabel.String = 'η';app.OPpic2.YLabel.String = 'θ';app.OPpic2.Title.String = 'η-θ';
app.OPpic3.XLabel.String = 'η';app.OPpic3.YLabel.String = 'r';app.OPpic3.Title.String = 'η-r';

app.pHpic1.XLabel.String = 'pH';app.pHpic1.YLabel.String = 'log(r)';app.pHpic1.Title.String = 'pH-log(r)';
app.pHpic2.XLabel.String = 'pH';app.pHpic2.YLabel.String = 'θ';app.pHpic2.Title.String = 'pH-θ';
app.pHpic3.XLabel.String = 'pH';app.pHpic3.YLabel.String = 'r';app.pHpic3.Title.String = 'pH-r';
        end

        % Button pushed function: MButton
        function MButtonPushed(app, event)
%% 创建LHLOM数据输出Excel表格文件
filename = 'LHLOM.xlsx';  % 指定要删除的文件名 
if exist("LHLOM.xlsx", 'file') == 2 % 检查文件是否存在 
    delete(filename); % 删除文件 
end 
% 创建空白表格
file = 'LHLOM.xlsx'; 
A = []; B = [];C = []; D = [];
writematrix(A,file,"Sheet",'Sheet1');
writematrix(B,file,"Sheet",'Sheet2');
writematrix(C,file,"Sheet",'Sheet3');
writematrix(D,file,"Sheet",'Sheet4');

% 写入标题行和相关参数
TitleRow1 = {'参数','数值','单位','η',...
'k1','k-1','k2-1','k-2-1','k2-2','k-2-2','k3-1','k-3-1','k3-2','k-3-2','k4','k-4','k5','k-5',...
'ΘVMV','ΘOHMV','ΘOHMOH','ΘOMV','ΘOMOH','ΘOMO',...
'r1','r2-1','r2-2','r3-1','r3-2','r4','r5','log(r1)'};
TitleRow2 = {'参数','数值','单位','pH',...
'k1','k-1','k2-1','k-2-1','k2-2','k-2-2','k3-1','k-3-1','k3-2','k-3-2','k4','k-4','k5','k-5',...
'ΘVMV','ΘOHMV','ΘOHMOH','ΘOMV','ΘOMOH','ΘOMO',...
'r1','r2-1','r2-2','r3-1','r3-2','r4','r5','log(r1)'};
CanShuName1 = {'kBT/h','kBT','T','f','Ea0','γ','β','pH','ΔG1','ΔG2_1','ΔG2_2','ΔG3_1',...
'ΔG3_2','ΔG4','ΔG5','λ1','λ2-1','λ2-2','λ3-1','λ3-2','λ4'}';
CanShuName2 = {'kBT/h','kBT','T','f','Ea0','γ','β','η','ΔG1','ΔG2_1','ΔG2_2','ΔG3_1',...
'ΔG3_2','ΔG4','ΔG5','λ1','λ2-1','λ2-2','λ3-1','λ3-2','λ4'}';
CanShuData = [6220889369003.16;0.0256925694830201;298.15;38.9217590969594];
CanShuUnit = {'s-1','eV','K','V-1','eV','','','','eV','eV','eV','eV','eV','eV','eV','eV','eV','eV','eV','eV','eV'}';
% 构建Shee1
writecell(TitleRow1,file,"Sheet",1,'Range','A1');
writecell(CanShuName1,file,"Sheet",1,'Range','A2');
writematrix(CanShuData,file,"Sheet",1,'Range','B2');
writecell(CanShuUnit,file,"Sheet",1,'Range','C2');
% 构建Shee3
writecell(TitleRow2,file,"Sheet",3,'Range','A1');
writecell(CanShuName2,file,"Sheet",3,'Range','A2');
writematrix(CanShuData,file,"Sheet",3,'Range','B2');
writecell(CanShuUnit,file,"Sheet",3,'Range','C2');

%% 获取参数并运算
filename = 'LHLOM.xlsx';
yita = (app.down.Value:app.step.Value:app.up.Value)';
pH = (app.pHdown.Value:app.pHstep.Value:app.pHup.Value)';
writematrix(app.G1.Value,filename,'Sheet',1 , 'Range', 'B10');
writematrix(app.G2_1.Value,filename,'Sheet',1, 'Range', 'B11');
writematrix(app.G2_2.Value,filename,'Sheet',1, 'Range', 'B12');
writematrix(app.G3_1.Value,filename,'Sheet',1, 'Range', 'B13');
writematrix(app.G3_2.Value,filename,'Sheet',1, 'Range', 'B14');
writematrix(app.G4.Value,filename,'Sheet',1, 'Range', 'B15');
writematrix(app.G5.Value,filename,'Sheet',1, 'Range', 'B16');
writematrix(app.G1.Value,filename,'Sheet',3 , 'Range', 'B10');
writematrix(app.G2_1.Value,filename,'Sheet',3, 'Range', 'B11');
writematrix(app.G2_2.Value,filename,'Sheet',3, 'Range', 'B12');
writematrix(app.G3_1.Value,filename,'Sheet',3, 'Range', 'B13');
writematrix(app.G3_2.Value,filename,'Sheet',3, 'Range', 'B14');
writematrix(app.G4.Value,filename,'Sheet',3, 'Range', 'B15');
writematrix(app.G5.Value,filename,'Sheet',3, 'Range', 'B16');

writematrix(app.lanmuda1.Value,filename,'Sheet', 1 , 'Range', 'B17');
writematrix(app.lanmuda2_1.Value,filename,'Sheet', 1 , 'Range', 'B18');
writematrix(app.lanmuda2_2.Value,filename,'Sheet', 1 , 'Range', 'B19');
writematrix(app.lanmuda3_1.Value,filename,'Sheet', 1 , 'Range', 'B20');
writematrix(app.lanmuda3_2.Value,filename,'Sheet', 1 , 'Range', 'B21');
writematrix(app.lanmuda4.Value,filename,'Sheet', 1 , 'Range', 'B22');
writematrix(app.lanmuda1.Value,filename,'Sheet', 3 , 'Range', 'B17');
writematrix(app.lanmuda2_1.Value,filename,'Sheet', 3 , 'Range', 'B18');
writematrix(app.lanmuda2_2.Value,filename,'Sheet', 3 , 'Range', 'B19');
writematrix(app.lanmuda3_1.Value,filename,'Sheet', 3 , 'Range', 'B20');
writematrix(app.lanmuda3_2.Value,filename,'Sheet', 3 , 'Range', 'B21');
writematrix(app.lanmuda4.Value,filename,'Sheet', 3 , 'Range', 'B22');

writematrix(yita,filename,'Sheet', 1, 'Range', 'D2');
writematrix(pH,filename,'Sheet', 3, 'Range', 'D2');
writematrix(app.pHding.Value,filename,'Sheet',1, 'Range', 'B9');
writematrix(app.yitading.Value,filename,'Sheet',3, 'Range', 'B9');

writematrix(app.Ea0.Value,filename,'Sheet', 1 , 'Range', 'B6');
writematrix(app.gama.Value,filename,'Sheet', 1 , 'Range', 'B7');
writematrix(app.beita.Value,filename,'Sheet', 1 , 'Range', 'B8');
writematrix(app.Ea0.Value,filename,'Sheet', 3 , 'Range', 'B6');
writematrix(app.gama.Value,filename,'Sheet', 3 , 'Range', 'B7');
writematrix(app.beita.Value,filename,'Sheet', 3 , 'Range', 'B8');
run("LHLOM_M.m");

% 读取Excel表格计算后的数据
t1 = readtable("LHLOM.xlsx",'VariableNamingRule', 'preserve',"Sheet",1,"Range",'D:AH');
t2 = readtable("LHLOM.xlsx",'VariableNamingRule', 'preserve',"Sheet",3,"Range",'D:AH');
app.OPtable.Data = t1;app.pHtable.Data = t2;
% η部分——图1的参数
x1 = table2array(t1(:,1));
log1 = table2array(t1(:,29));
% η部分——图2纵坐标的参数
y3 = table2array(t1(:,16));y4 = table2array(t1(:,17));y5 = table2array(t1(:,18));
y6 = table2array(t1(:,19));y7 = table2array(t1(:,20));y8 = table2array(t1(:,21));
% η部分——图3纵坐标的参数
ro1 = table2array(t1(:,22));ro2_1 = table2array(t1(:,23));ro2_2 = table2array(t1(:,24));
ro3_1 = table2array(t1(:,25));ro3_2 = table2array(t1(:,26));ro4 = table2array(t1(:,27));
ro5 = table2array(t1(:,28));
% pH部分——图1的参数
x2 = table2array(t2(:,1));
log2 = table2array(t2(:,29));
% pH部分——图2纵坐标的参数
y9 = table2array(t2(:,16));y10 = table2array(t2(:,17));y11 = table2array(t2(:,18));
y12 = table2array(t2(:,19));y13 = table2array(t2(:,20));y14 = table2array(t2(:,21));
% pH部分——图3纵坐标的参数
rp1 = table2array(t2(:,22));rp2_1 = table2array(t2(:,23));rp2_2 = table2array(t2(:,24));
rp3_1 = table2array(t2(:,25));rp3_2 = table2array(t2(:,26));rp4 = table2array(t2(:,27));
rp5 = table2array(t2(:,28));

% 清除异常数据 
max = 200;
valid_indices1 = log1 < max;  % 生成逻辑索引 
valid_indices2 = log2 < max;  % 生成逻辑索引 
x1_clean = x1(valid_indices1);
y1_clean = log1(valid_indices1);
x2_clean = x2(valid_indices2);
y2_clean = log2(valid_indices2);

% 清除坐标轴并绘制η部分图1
cla(app.OPpic1);
plot(app.OPpic1, x1_clean, y1_clean);
% 清除坐标轴并绘制η部分图2
cla(app.OPpic2);
plot(app.OPpic2, x1, y3,'r',x1, y4,'b',x1,y5,'g',x1,y6,'k',x1,y7,'y',x1,y8,'m');
legend(app.OPpic2,'VMV','OHMV','OHMOH','OMV','OMOH','OMO');
% 清除坐标轴并绘制η部分图3
cla(app.OPpic3);
plot(app.OPpic3,x1,ro1,'r',x1, ro2_1,'b',x1,ro3_1,'g',x1,ro2_2,'k',x1,ro3_2,'y',x1,ro4,'m',x1,ro5,'c');
legend(app.OPpic3,'r1','r2-1','r3-1','r2-2','r3-2','r4','r5');
% 清除坐标轴并绘制pH部分图1 
cla(app.pHpic1);
plot(app.pHpic1, x2_clean,y2_clean);
% 清除坐标轴并绘制pH部分图2
cla(app.pHpic2);
plot(app.pHpic2, x2, y9,'r',x2,y10,'b',x2,y11,'g',x2,y12,'k',x2,y13,'y',x2,y14,'m');
legend(app.pHpic2,'VMV','OHMV','OHMOH','OMV','OMOH','OMO');
% 清除坐标轴并绘制pH部分图3
cla(app.pHpic3);
plot(app.pHpic3,x2,rp1,'r',x2, rp2_1,'b',x2,rp3_1,'g',x2,rp2_2,'k',x2,rp3_2,'y',x2,rp4,'m',x2,rp5,'c');
legend(app.pHpic3,'r1','r2-1','r3-1','r2-2','r3-2','r4','r5');

% 添加坐标轴标签 
app.OPpic1.XLabel.String = 'η';app.OPpic1.YLabel.String = 'log(r)';app.OPpic1.Title.String = 'η-log(r)';
app.OPpic2.XLabel.String = 'η';app.OPpic2.YLabel.String = 'θ';app.OPpic2.Title.String = 'η-θ';
app.OPpic3.XLabel.String = 'η';app.OPpic3.YLabel.String = 'r';app.OPpic3.Title.String = 'η-r';

app.pHpic1.XLabel.String = 'pH';app.pHpic1.YLabel.String = 'log(r)';app.pHpic1.Title.String = 'pH-log(r)';
app.pHpic2.XLabel.String = 'pH';app.pHpic2.YLabel.String = 'θ';app.pHpic2.Title.String = 'pH-θ';
app.pHpic3.XLabel.String = 'pH';app.pHpic3.YLabel.String = 'r';app.pHpic3.Title.String = 'pH-r';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LeftPanel = uipanel(app.UIFigure);
            app.G1EditFieldLabel = uilabel(app.LeftPanel);
            app.G1EditFieldLabel.Text = 'G1';
            app.G2_1EditFieldLabel = uilabel(app.LeftPanel);
            app.G2_1EditFieldLabel.Text = 'G2_1';
            app.G2_2EditFieldLabel = uilabel(app.LeftPanel);
            app.G2_2EditFieldLabel.Text = 'G2_2';
            app.G3_1EditFieldLabel = uilabel(app.LeftPanel);
            app.G3_1EditFieldLabel.Text = 'G3_1';
            app.EditFieldLabel = uilabel(app.LeftPanel);
            app.EditFieldLabel.Text = 'λ1';
            app.Label_11 = uilabel(app.LeftPanel);
            app.Label_11.Text = 'λ2_1';
            app.Label_12 = uilabel(app.LeftPanel);
            app.Label_12.Text = 'λ2_2';
            app.Label_13 = uilabel(app.LeftPanel);
            app.Label_13.Text = 'λ3_1';
            app.Label = uilabel(app.LeftPanel);
            app.Label.Text = '上限';
            app.EditField_3Label_2 = uilabel(app.LeftPanel);
            app.EditField_3Label_2.Text = '步长';
            app.step = uieditfield(app.LeftPanel, 'numeric');
            app.step.Value = 0.01;
            app.EditField_2Label_2 = uilabel(app.LeftPanel);
            app.EditField_2Label_2.Text = '下限';
            app.down = uieditfield(app.LeftPanel, 'numeric');
            app.down.Value = -1;
            app.up = uieditfield(app.LeftPanel, 'numeric');
            app.up.Value = 1;
            app.yitaLabel = uilabel(app.LeftPanel);
            app.yitaLabel.Text = 'η';
            app.pHEditFieldLabel = uilabel(app.LeftPanel);
            app.pHEditFieldLabel.Text = 'pH';
            app.pHding = uieditfield(app.LeftPanel, 'numeric');
            app.Ea0Label = uilabel(app.LeftPanel);
            app.Ea0Label.Text = 'Ea,0';
            app.Ea0 = uieditfield(app.LeftPanel, 'numeric');
            app.Ea0.Value = 0.5;
            app.Label_6 = uilabel(app.LeftPanel);
            app.Label_6.Text = 'β';
            app.beita = uieditfield(app.LeftPanel, 'numeric');
            app.beita.Value = 0.5;
            app.Label_7 = uilabel(app.LeftPanel);
            app.Label_7.Text = 'γ';
            app.gama = uieditfield(app.LeftPanel, 'numeric');
            app.gama.Value = 0.5;
            app.MGButton = uibutton(app.LeftPanel, 'push');
            app.MGButton.ButtonPushedFcn = createCallbackFcn(app, @MGButtonPushed, true);
            app.MGButton.Text = 'MG';
            app.MButton = uibutton(app.LeftPanel, 'push');
            app.MButton.ButtonPushedFcn = createCallbackFcn(app, @MButtonPushed, true);
            app.MButton.Text = 'M';
            app.BVButton = uibutton(app.LeftPanel, 'push');
            app.BVButton.ButtonPushedFcn = createCallbackFcn(app, @BVButtonPushed, true);
            app.BVButton.Text = 'BV';
            app.MarcusMarcusGerischerkineticsLabel = uilabel(app.LeftPanel);
            app.MarcusMarcusGerischerkineticsLabel.Text = 'Marcus & Marcus-Gerischer kinetics';
            app.ButlerVolmerkineticsLabel = uilabel(app.LeftPanel);
            app.ButlerVolmerkineticsLabel.Text = 'Butler-Volmer kinetics';
            app.LHLOMLabel = uilabel(app.LeftPanel);
            app.LHLOMLabel.Text = 'LHLOM';
            app.G3_2EditFieldLabel = uilabel(app.LeftPanel);
            app.G3_2EditFieldLabel.Text = 'G3_2';
            app.G4EditFieldLabel = uilabel(app.LeftPanel);
            app.G4EditFieldLabel.Text = 'G4';
            app.G5EditFieldLabel = uilabel(app.LeftPanel);
            app.G5EditFieldLabel.Text = 'G5';
            app.Label_14 = uilabel(app.LeftPanel);
            app.Label_14.Text = 'λ3_2';
            app.EditField_4Label = uilabel(app.LeftPanel);
            app.EditField_4Label.Text = 'λ4';
            app.lanmuda4 = uieditfield(app.LeftPanel, 'numeric');
            app.lanmuda4.Value = 2;
            app.lanmuda3_2 = uieditfield(app.LeftPanel, 'numeric');
            app.lanmuda3_2.Value = 2;
            app.lanmuda3_1 = uieditfield(app.LeftPanel, 'numeric');
            app.lanmuda3_1.Value = 2;
            app.lanmuda2_2 = uieditfield(app.LeftPanel, 'numeric');
            app.lanmuda2_2.Value = 2;
            app.lanmuda2_1 = uieditfield(app.LeftPanel, 'numeric');
            app.lanmuda2_1.Value = 2;
            app.lanmuda1 = uieditfield(app.LeftPanel, 'numeric');
            app.lanmuda1.Value = 2;
            app.G5 = uieditfield(app.LeftPanel, 'numeric');
            app.G4 = uieditfield(app.LeftPanel, 'numeric');
            app.G3_2 = uieditfield(app.LeftPanel, 'numeric');
            app.G3_1 = uieditfield(app.LeftPanel, 'numeric');
            app.G2_2 = uieditfield(app.LeftPanel, 'numeric');
            app.G2_1 = uieditfield(app.LeftPanel, 'numeric');
            app.G1 = uieditfield(app.LeftPanel, 'numeric');
            app.Label1_3 = uilabel(app.LeftPanel);
            app.Label1_3.Text = '参数范围';
            app.Label_16 = uilabel(app.LeftPanel);
            app.Label_16.Text = 'η';
            app.yitading = uieditfield(app.LeftPanel, 'numeric');
            app.pHLabel = uilabel(app.LeftPanel);
            app.pHLabel.Text = 'pH';
            app.Label_17 = uilabel(app.LeftPanel);
            app.Label_17.Text = '上限';
            app.pHup = uieditfield(app.LeftPanel, 'numeric');
            app.pHup.Value = 14;
            app.EditField_3Label_3 = uilabel(app.LeftPanel);
            app.EditField_3Label_3.Text = '下限';
            app.pHdown = uieditfield(app.LeftPanel, 'numeric');
            app.EditField_4Label_2 = uilabel(app.LeftPanel);
            app.EditField_4Label_2.Text = '步长';
            app.pHstep = uieditfield(app.LeftPanel, 'numeric');
            app.pHstep.Value = 0.1;
            app.Label1_4 = uilabel(app.LeftPanel);
            app.Label1_4.Text = '参数范围';
            app.CenterPanel = uipanel(app.UIFigure);
            app.OPpic1 = uiaxes(app.CenterPanel);
            title(app.OPpic1, 'Title')
            xlabel(app.OPpic1, 'X')
            ylabel(app.OPpic1, 'Y')
            zlabel(app.OPpic1, 'Z')
            app.OPpic2 = uiaxes(app.CenterPanel);
            title(app.OPpic2, 'Title')
            xlabel(app.OPpic2, 'X')
            ylabel(app.OPpic2, 'Y')
            zlabel(app.OPpic2, 'Z')
            app.OPpic3 = uiaxes(app.CenterPanel);
            title(app.OPpic3, 'Title')
            xlabel(app.OPpic3, 'X')
            ylabel(app.OPpic3, 'Y')
            zlabel(app.OPpic3, 'Z')
            app.OPtable = uitable(app.CenterPanel);
            app.OPtable.ColumnName = {'η'; 'k1'; 'k-1'; 'k2-1'; 'k-2-1'; 'k2-2'; 'k-2-2'; 'k3-1'; 'k-3-1'; 'k3-2'; 'k-3-2'; 'k4'; 'k-4'; 'k5'; 'k-5'; 'VMV'; 'OHMV'; 'OHMOH'; 'OMV'; 'OMOH'; 'OMO'; 'r1'; 'r2_1'; 'r2_2'; 'r3_1'; 'r3_2'; 'r4'; 'r5'; 'log(r1)'; 'log(r2-1)'; 'log(r2-2)'};
            app.OPtable.RowName = {};
            app.RightPanel = uipanel(app.UIFigure);
            app.pHpic1 = uiaxes(app.RightPanel);
            title(app.pHpic1, 'Title')
            xlabel(app.pHpic1, 'X')
            ylabel(app.pHpic1, 'Y')
            zlabel(app.pHpic1, 'Z')
            app.pHpic2 = uiaxes(app.RightPanel);
            title(app.pHpic2, 'Title')
            xlabel(app.pHpic2, 'X')
            ylabel(app.pHpic2, 'Y')
            zlabel(app.pHpic2, 'Z')
            app.pHpic3 = uiaxes(app.RightPanel);
            title(app.pHpic3, 'Title')
            xlabel(app.pHpic3, 'X')
            ylabel(app.pHpic3, 'Y')
            zlabel(app.pHpic3, 'Z')
            app.pHtable = uitable(app.RightPanel);
            app.pHtable.ColumnName = {'pH'; 'k1'; 'k-1'; 'k2-1'; 'k-2-1'; 'k2-2'; 'k-2-2'; 'k3-1'; 'k-3-1'; 'k3-2'; 'k-3-2'; 'k4'; 'k-4'; 'k5'; 'k-5'; 'VMV'; 'OHMV'; 'OHMOH'; 'OMV'; 'OMOH'; 'OMO'; 'r1'; 'r2_1'; 'r2_2'; 'r3_1'; 'r3_2'; 'r4'; 'r5'; 'log(r1)'; 'log(r2-1)'; 'log(r2-2)'};
            app.pHtable.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LHLOM

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end